classdef voiceRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpeechRecognitionUIFigure     matlab.ui.Figure
        NameofthePersonTextArea       matlab.ui.control.TextArea
        NameofthePersonTextAreaLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        TimeTab                       matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        PreprocessedTab               matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        FrecuencyTab                  matlab.ui.container.Tab
        MagnitudeButtonGroup          matlab.ui.container.ButtonGroup
        DBButton                      matlab.ui.control.RadioButton
        NormalButton                  matlab.ui.control.RadioButton
        MenuButtonGroup               matlab.ui.container.ButtonGroup
        PreprocessedButton            matlab.ui.control.Button
        TimeButton                    matlab.ui.control.Button
        UIAxes4                       matlab.ui.control.UIAxes
        Tab                           matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu_2                        matlab.ui.container.Menu
        Menu2_2                       matlab.ui.container.Menu
    end

    
    properties (Access = public)
        timeValuesCapture = [];          % Arreglo con los valores tomados de la capura de datos.
        preProcesadoValores = [];        % Arreglo con los valores preprocesados.
    end
    
    properties (Access = private)
        audioPlayer = [];                % Propiedad para reconocer si se está reproduciendo un audio.
        swithWindow = 0;                 % Variable para saber en que ventana está parado.
        maginitudeFrecuency = 0;         % Valor de la magnitud a representar.
        frecSignal = []                  % Arreglo con la señal transformada de fourier.

        elapsedTime = 0;                 % Variable para manejar el tiempo.
        barTime                          % Variable para controlar la barra de carga de la grabación.
        time = 5                         % Tiempo de grabación.
    end
    
    methods (Access = public)
        %% Función para graficar los resultados de la grabación grabada:
        function graficarTime(app, xValues, yValues)
            stem(app.UIAxes, xValues, yValues, '.');
            app.timeValuesCapture = [xValues; yValues'];
        end
        
        %% Función para graficar los resultados de preProcesamiento:
        function graficarPreprocesamiento(app, xValues, yValues)
            stem(app.UIAxes3, xValues, yValues, '.');
            app.preProcesadoValores = [xValues; yValues'];
        end
    end
    
    methods (Access = private)
        %% Función para graficar en el dominio frecuencial:
        function data = plot_fftStat(app, signal)
            % Realizar FFT normal
            fft_result = fft(signal);

            % Obtener la mitad positiva de la FFT
            fft_result = fft_result(1:length(fft_result)/2);
            fft_result(fft_result == 0) = eps;

            if(app.maginitudeFrecuency == 0)
                % Calcular la magnitud normal:
                fft_result_dB = (abs(fft_result) / length(fft_result));
            else
                % Calcular la magnitud en decibeles
                fft_result_dB = 20*log10(abs(fft_result) / length(fft_result));
            end

            % Generar el vector de frecuencias para la mitad positiva
            f = linspace(0, 22050, length(fft_result));
            data = [fft_result_dB; f];
        end

        %% Función para contablizar el tiempo de grabación:
        function timer_callback(app)
            % Incrementar el tiempo en décimas de segundo
            app.elapsedTime = app.elapsedTime + 0.1;

            % Convertir el tiempo a segundos y microsegundos
            seconds = floor(app.elapsedTime);
            microSeconds = mod(app.elapsedTime, 1);

            % Actualizar la etiqueta de texto con el tiempo transcurrido
            value = ((seconds + microSeconds) / app.time);
            waitbar(value, app.barTime, 'Recording');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            % Configura la posición de la figura para centrarla:
            movegui(app.SpeechRecognitionUIFigure, 'center');
            app.swithWindow = 1;
            app.maginitudeFrecuency = 0;
            xlabel(app.UIAxes4,'Frequency (Hz)');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           % Configurar el temporizador
            app.elapsedTime = 0;
            app.Button.Enable = 'off';
            Timer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @(~,~)timer_callback(app));
            fs = 44100;  % Frecuencia de muestreo en Hz (puedes ajustarla según tus necesidades)
            recObj = audiorecorder(fs, 16, 1);  % Crear un objeto de grabadora de audio
            % Iniciar el temporizador
            app.barTime = waitbar(0,'Recording');
            start(Timer);
            recordblocking(recObj, app.time); % Grabar  audio
            % Detener contador.
            close(app.barTime);
            stop(Timer);
            audioData = getaudiodata(recObj);
            xlength = 0:1:length(audioData)- 1;
            % Guardar arreglo en la aplición principal:
            app.graficarTime(xlength, audioData);
            %Limpiar memoria:
            clear Timer recObj audioData xlength app.barTime
            app.TabGroup.SelectedTab = app.TimeTab;
            app.Button.Enable = 'on';
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            switch app.swithWindow
                case 1
                    if ~isempty(app.timeValuesCapture)
                        app.audioPlayer = audioplayer(app.timeValuesCapture(2,:), 44100);
                        play(app.audioPlayer);
                        clear app.audioPlayer;
                    else
                        msgbox('No voice has been recorded', 'Error', 'error');
                        return;
                    end
                case 2
                    if ~isempty(app.preProcesadoValores)
                        app.audioPlayer = audioplayer(app.preProcesadoValores(2,:), 44100);
                        play(app.audioPlayer);
                        clear app.audioPlayer;
                    else
                        msgbox('No signal has been preprocessed', 'Error', 'error');
                        return;
                    end
                otherwise
                        msgbox('Non-audible signal', 'Error', 'error');
                        return;
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             if isa(app.audioPlayer, 'audioplayer') && (isplaying(app.audioPlayer))
                % Detener la reproducción
                stop(app.audioPlayer);
                clear app.audioPlayer;
            end
        end

        % Button down function: TimeTab
        function TimeTabButtonDown(app, event)
            app.swithWindow = 1;
        end

        % Button down function: PreprocessedTab
        function PreprocessedTabButtonDown(app, event)
            app.swithWindow = 2;
        end

        % Button down function: FrecuencyTab
        function FrecuencyTabButtonDown(app, event)
            app.swithWindow = 3;
        end

        % Button pushed function: TimeButton
        function TimeButtonPushed(app, event)
            if ~isempty(app.timeValuesCapture)
                app.UIAxes4.Title.String = "Recorder";
                app.frecSignal = app.timeValuesCapture(2,:);
                data = app.plot_fftStat(app.frecSignal);
                plot(app.UIAxes4, data(2,:), data(1,:));
                if(app.maginitudeFrecuency == 0)
                    ylabel(app.UIAxes4,'Magnitude');
                else
                    ylabel(app.UIAxes4,'Magnitude DB');
                end
            else
                msgbox('No voice has been recorded', 'Error', 'error');
                return;
            end
        end

        % Selection changed function: MagnitudeButtonGroup
        function MagnitudeButtonGroupSelectionChanged(app, event)
            selectedButton = app.MagnitudeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Normal')
                app.maginitudeFrecuency = 0;
            else
                app.maginitudeFrecuency = 1;
            end
            if ~isempty(app.frecSignal)
                data = app.plot_fftStat(app.frecSignal);
                plot(app.UIAxes4, data(2,:), data(1,:));
                if(app.maginitudeFrecuency == 0)
                    ylabel(app.UIAxes4,'Magnitude');
                else
                    ylabel(app.UIAxes4,'Magnitude DB');
                end
            end
        end

        % Button pushed function: PreprocessedButton
        function PreprocessedButtonPushed(app, event)
            if ~isempty(app.timeValuesCapture)
                app.UIAxes4.Title.String = "Recorder";
                app.frecSignal = app.preProcesadoValores(2,:);
                data = app.plot_fftStat(app.frecSignal);
                plot(app.UIAxes4, data(2,:), data(1,:));
                if(app.maginitudeFrecuency == 0)
                    ylabel(app.UIAxes4,'Magnitude');
                else
                    ylabel(app.UIAxes4,'Magnitude DB');
                end
            else
                msgbox('No voice has been preprocessed', 'Error', 'error');
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpeechRecognitionUIFigure = uifigure('Visible', 'off');
            app.SpeechRecognitionUIFigure.Name = 'Speech Recognition';
            app.Panel = uipanel(app.SpeechRecognitionUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'reproducirM.png';
            app.Button_2.Text = '';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'voz.png';
            app.Button.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'boton-detenerM.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.Icon = 'cheque.png';
            app.Button_4.Text = '';
            app.TabGroup = uitabgroup(app.SpeechRecognitionUIFigure);
            app.TimeTab = uitab(app.TabGroup);
            app.TimeTab.Title = 'Time';
            app.TimeTab.ButtonDownFcn = createCallbackFcn(app, @TimeTabButtonDown, true);
            app.UIAxes = uiaxes(app.TimeTab);
            xlabel(app.UIAxes, 'Seconds')
            ylabel(app.UIAxes, 'Voice Level')
            zlabel(app.UIAxes, 'Z')
            app.PreprocessedTab = uitab(app.TabGroup);
            app.PreprocessedTab.Title = 'Preprocessed';
            app.PreprocessedTab.ButtonDownFcn = createCallbackFcn(app, @PreprocessedTabButtonDown, true);
            app.UIAxes3 = uiaxes(app.PreprocessedTab);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.FrecuencyTab = uitab(app.TabGroup);
            app.FrecuencyTab.Title = 'Frecuency';
            app.FrecuencyTab.ButtonDownFcn = createCallbackFcn(app, @FrecuencyTabButtonDown, true);
            app.UIAxes4 = uiaxes(app.FrecuencyTab);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.MenuButtonGroup = uibuttongroup(app.FrecuencyTab);
            app.MenuButtonGroup.Title = 'Menu';
            app.TimeButton = uibutton(app.MenuButtonGroup, 'push');
            app.TimeButton.ButtonPushedFcn = createCallbackFcn(app, @TimeButtonPushed, true);
            app.TimeButton.Text = 'Time';
            app.PreprocessedButton = uibutton(app.MenuButtonGroup, 'push');
            app.PreprocessedButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessedButtonPushed, true);
            app.PreprocessedButton.Text = 'Preprocessed';
            app.MagnitudeButtonGroup = uibuttongroup(app.FrecuencyTab);
            app.MagnitudeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MagnitudeButtonGroupSelectionChanged, true);
            app.MagnitudeButtonGroup.Title = 'Magnitude';
            app.NormalButton = uiradiobutton(app.MagnitudeButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.DBButton = uiradiobutton(app.MagnitudeButtonGroup);
            app.DBButton.Text = 'DB';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.NameofthePersonTextAreaLabel = uilabel(app.SpeechRecognitionUIFigure);
            app.NameofthePersonTextAreaLabel.Text = 'Name of the Person';
            app.NameofthePersonTextArea = uitextarea(app.SpeechRecognitionUIFigure);
            app.NameofthePersonTextArea.Editable = 'off';
            app.ContextMenu = uicontextmenu(app.SpeechRecognitionUIFigure);
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SpeechRecognitionUIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.SpeechRecognitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voiceRecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpeechRecognitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpeechRecognitionUIFigure)
        end
    end
end